classdef ColorsRGB < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ColorsRGBUIFigure  matlab.ui.Figure
        Label_8            matlab.ui.control.Label
        Label_7            matlab.ui.control.Label
        Label_6            matlab.ui.control.Label
        Label_5            matlab.ui.control.Label
        Label_4            matlab.ui.control.Label
        Label_3            matlab.ui.control.Label
        EditFieldBlue      matlab.ui.control.NumericEditField
        EditField_3Label   matlab.ui.control.Label
        EditFieldGreen     matlab.ui.control.NumericEditField
        EditField_2Label   matlab.ui.control.Label
        EditFieldRed       matlab.ui.control.NumericEditField
        Label_2            matlab.ui.control.Label
        ResetButton        matlab.ui.control.Button
        Label2             matlab.ui.control.Label
        BlueSlider         matlab.ui.control.Slider
        Label              matlab.ui.control.Label
        GreenSlider        matlab.ui.control.Slider
        RedSlider          matlab.ui.control.Slider
        LabelSlider        matlab.ui.control.Label
        UIAxes             matlab.ui.control.UIAxes
    end


    properties (Access = private)
        I
        J
        meanRed
        meanGreen
        meanBlue
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.I = uint8(zeros(500,600,3));
            app.J = app.I;
            imshow(app.I,'Parent',app.UIAxes)
            app.UIAxes.XTickMode = 'manual';
            app.UIAxes.YTickMode = 'manual';
            
        end

        % Value changed function: RedSlider
        function RedSliderValueChanged(app, event)
            app.J(:,:,1) = app.I(:,:,1) + app.RedSlider.Value;
            app.J(:,:,2) = app.I(:,:,2) + app.GreenSlider.Value;
            app.J(:,:,3) = app.I(:,:,3) + app.BlueSlider.Value;
            imshow(app.J,'Parent',app.UIAxes)
            app.EditFieldRed.Value = app.RedSlider.Value;
            app.EditFieldGreen.Value = app.GreenSlider.Value;
            app.EditFieldBlue.Value = app.BlueSlider.Value;
        end

        % Value changed function: GreenSlider
        function GreenSliderValueChanged(app, event)
            app.J(:,:,1) = app.I(:,:,1) + app.RedSlider.Value;
            app.J(:,:,2) = app.I(:,:,2) + app.GreenSlider.Value;
            app.J(:,:,3) = app.I(:,:,3) + app.BlueSlider.Value;
            imshow(app.J,'Parent',app.UIAxes)
            app.EditFieldRed.Value = app.RedSlider.Value;
            app.EditFieldGreen.Value = app.GreenSlider.Value;
            app.EditFieldBlue.Value = app.BlueSlider.Value;
        end

        % Value changed function: BlueSlider
        function BlueSliderValueChanged(app, event)
            app.J(:,:,1) = app.I(:,:,1) + app.RedSlider.Value;
            app.J(:,:,2) = app.I(:,:,2) + app.GreenSlider.Value;
            app.J(:,:,3) = app.I(:,:,3) + app.BlueSlider.Value;
            imshow(app.J,'Parent',app.UIAxes)
            app.EditFieldRed.Value = app.RedSlider.Value;
            app.EditFieldGreen.Value = app.GreenSlider.Value;
            app.EditFieldBlue.Value = app.BlueSlider.Value;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            imshow(app.I,'Parent',app.UIAxes)
            app.J = app.I;
            app.RedSlider.Value = 0;
            app.GreenSlider.Value = 0;
            app.BlueSlider.Value = 0;
            app.EditFieldRed.Value = app.RedSlider.Value;
            app.EditFieldGreen.Value = app.GreenSlider.Value;
            app.EditFieldBlue.Value = app.BlueSlider.Value;
        end

        % Value changed function: EditFieldRed
        function editRedTextBoxValueChanged(app, event)
            value = app.EditFieldRed.Value;
            app.RedSlider.Value = value;
            app.RedSliderValueChanged;
        end

        % Value changed function: EditFieldGreen
        function editGreenTextBoxValueChanged(app, event)
            value = app.EditFieldGreen.Value;
            app.GreenSlider.Value = value;
            app.GreenSliderValueChanged;
        end

        % Value changed function: EditFieldBlue
        function editBlueTextBoxValueChanged(app, event)
            value = app.EditFieldBlue.Value;
            app.BlueSlider.Value = value;
            app.BlueSliderValueChanged;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ColorsRGBUIFigure = uifigure('Visible', 'off');
            app.ColorsRGBUIFigure.Name = 'Colors RGB';
            app.UIAxes = uiaxes(app.ColorsRGBUIFigure);
            app.LabelSlider = uilabel(app.ColorsRGBUIFigure);
            app.LabelSlider.Text = 'Red';
            app.RedSlider = uislider(app.ColorsRGBUIFigure);
            app.RedSlider.Limits = [0 255];
            app.RedSlider.MajorTicks = [0 50 100 150 200 255];
            app.RedSlider.MajorTickLabels = {'0', '50', '100', '150', '200', '255'};
            app.RedSlider.ValueChangedFcn = createCallbackFcn(app, @RedSliderValueChanged, true);
            app.RedSlider.MinorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100 105 110 115 120 125 130 135 140 145 150 155 160 165 170 175 180 185 190 195 200 205 210 215 220 225 230 235 240 245 250 255];
            app.GreenSlider = uislider(app.ColorsRGBUIFigure);
            app.GreenSlider.Limits = [0 255];
            app.GreenSlider.MajorTicks = [0 50 100 150 200 255];
            app.GreenSlider.MajorTickLabels = {'0', '50', '100', '150', '200', '255'};
            app.GreenSlider.ValueChangedFcn = createCallbackFcn(app, @GreenSliderValueChanged, true);
            app.GreenSlider.MinorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100 105 110 115 120 125 130 135 140 145 150 155 160 165 170 175 180 185 190 195 200 205 210 215 220 225 230 235 240 245 250 255];
            app.Label = uilabel(app.ColorsRGBUIFigure);
            app.Label.Text = 'Green';
            app.BlueSlider = uislider(app.ColorsRGBUIFigure);
            app.BlueSlider.Limits = [0 255];
            app.BlueSlider.MajorTicks = [0 50 100 150 200 255];
            app.BlueSlider.MajorTickLabels = {'0', '50', '100', '150', '200', '255'};
            app.BlueSlider.ValueChangedFcn = createCallbackFcn(app, @BlueSliderValueChanged, true);
            app.BlueSlider.MinorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100 105 110 115 120 125 130 135 140 145 150 155 160 165 170 175 180 185 190 195 200 205 210 215 220 225 230 235 240 245 250 255];
            app.Label2 = uilabel(app.ColorsRGBUIFigure);
            app.Label2.Text = 'Blue';
            app.ResetButton = uibutton(app.ColorsRGBUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Label_2 = uilabel(app.ColorsRGBUIFigure);
            app.Label_2.Text = '';
            app.EditFieldRed = uieditfield(app.ColorsRGBUIFigure, 'numeric');
            app.EditFieldRed.Limits = [0 255];
            app.EditFieldRed.RoundFractionalValues = 'on';
            app.EditFieldRed.ValueDisplayFormat = '%.0f';
            app.EditFieldRed.ValueChangedFcn = createCallbackFcn(app, @editRedTextBoxValueChanged, true);
            app.EditField_2Label = uilabel(app.ColorsRGBUIFigure);
            app.EditField_2Label.Text = '';
            app.EditFieldGreen = uieditfield(app.ColorsRGBUIFigure, 'numeric');
            app.EditFieldGreen.Limits = [0 255];
            app.EditFieldGreen.RoundFractionalValues = 'on';
            app.EditFieldGreen.ValueDisplayFormat = '%.0f';
            app.EditFieldGreen.ValueChangedFcn = createCallbackFcn(app, @editGreenTextBoxValueChanged, true);
            app.EditField_3Label = uilabel(app.ColorsRGBUIFigure);
            app.EditField_3Label.Text = '';
            app.EditFieldBlue = uieditfield(app.ColorsRGBUIFigure, 'numeric');
            app.EditFieldBlue.Limits = [0 255];
            app.EditFieldBlue.RoundFractionalValues = 'on';
            app.EditFieldBlue.ValueDisplayFormat = '%.0f';
            app.EditFieldBlue.ValueChangedFcn = createCallbackFcn(app, @editBlueTextBoxValueChanged, true);
            app.Label_3 = uilabel(app.ColorsRGBUIFigure);
            app.Label_3.Text = '0';
            app.Label_4 = uilabel(app.ColorsRGBUIFigure);
            app.Label_4.Text = '0';
            app.Label_5 = uilabel(app.ColorsRGBUIFigure);
            app.Label_5.Text = '0';
            app.Label_6 = uilabel(app.ColorsRGBUIFigure);
            app.Label_6.Text = '255';
            app.Label_7 = uilabel(app.ColorsRGBUIFigure);
            app.Label_7.Text = '255';
            app.Label_8 = uilabel(app.ColorsRGBUIFigure);
            app.Label_8.Text = '255';
            % Show the figure after all components are created
            app.ColorsRGBUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ColorsRGB

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ColorsRGBUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ColorsRGBUIFigure)
        end
    end
end